classdef SafetyList_Test_GUI_MATLAB_Runtime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RunButton               matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        HHITestAutomationSuiteSafetyListLabel  matlab.ui.control.Label
        TestLogTextAreaLabel    matlab.ui.control.Label
        TestLogTextAreaLabel_2  matlab.ui.control.Label
        FuelModeLabel           matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        BackupCheckBox          matlab.ui.control.CheckBox
        GasCheckBox             matlab.ui.control.CheckBox
        DieselCheckBox          matlab.ui.control.CheckBox
        AllCheckBox             matlab.ui.control.CheckBox
        TextArea_2              matlab.ui.control.TextArea
        RunModeLabel            matlab.ui.control.Label
        RunCheckBox             matlab.ui.control.CheckBox
        StartCheckBox           matlab.ui.control.CheckBox
        StopCheckBox            matlab.ui.control.CheckBox
        AllCheckBox_2           matlab.ui.control.CheckBox
        TestSelectionLabel      matlab.ui.control.Label
        v001Label               matlab.ui.control.Label
        AllCheckBox_4           matlab.ui.control.CheckBox
        ListBox                 matlab.ui.control.ListBox
        ListBox_2               matlab.ui.control.ListBox
        PLCIPTextAreaLabel      matlab.ui.control.Label
        PLCIPTextArea           matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        check_all = 0;
        fuelmode = 0;
        opmode = 0;
%         testcase = ["Setting"];
        testcase = [];
        fuel = 0;
        op = 0;
        testname;
        check_all_demo = 0;
        tab = [];
        tab_all = {};
        cbx = {};
        tab_num = [];
        item_box_empty = {};
    end
    methods (Access=private)
        function all_check_callback(app, cbx_temp)
            cbx = app.cbx;
            value = cbx_temp.Value;
            tab_num = app.tab_num;
            n = str2double(cbx_temp.Tag);
            a = 1;
            b = 1;
            for i = 1:n-1
                a=a+tab_num(i);
            end
            for i = 1:n
                b = b + tab_num(i);
            end
            b = b-1;
            if value == 1
                for i = a:b 
                    ta = cbx{i}; 
                    ta.Value = 1;
                end
            end
            if value == 0
                for i = a:b 
                    ta = cbx{i}; 
                    ta.Value = 0;
                end
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; close all;
%             app.PLCNoEditField.Value = 1;
            app.PLCIPTextArea.Value = '172.16.9.101';
            addpath(pwd);
            path = string(pwd);
            excel_file = "\safetylist_auto_integration.xlsx";
%             global tab; global tab_all;
%             T1 = readtable(path+excel_file);
%             T1 = readtable("C:\safetylist_auto_integration.xlsx");
            load("C:\Table.mat")
            name_code = T1(:,4);
            name_code = table2array(name_code);
            Tab_name = [];
            tab_num = [];
            cbx = {};
            k = 1;
            l = 1;
            m = 1;
            n = 1;
            k_temp = 2;
%             cbx_pos = [10 478 565 22];
            tabgp = uitabgroup(app.UIFigure,'Position',[19 140 325 535]);
%             tabgp = uitabgroup(app.UIFigure,'Position',[12 250 299 22]);
            N = 0;
            Tab_num = 1;
            T1_len = length(name_code);
            temp_test_name = [];
            for i = 6:T1_len-3
                a = name_code(i);
                b = name_code(i+1);
                a = a{1,1};
                b = b{1,1};
                if k > 1
                    cbx_pos = [10 500 565 22];
                    if k_temp~=k
                        l = 1;
                        k_temp = k;
                    end
                    A = T1(i,4);
                    A = A{1,1};
                    A = erase(A{1,1},char(10));
                    B = T1(i,6);
                    B = B{1,1};
                    B = erase(B{1,1},char(10));
                    C = T1(i,26);
                    C = C{1,1};
                    C = erase(C,char(10));
                    D = A + " / " + B + " / " + C;
                    if isequal(C{1,1},'.')==0
                        new_pos = cbx_pos-(k_temp-k+1)*(N-1)*[0 20 0 0];
                        cbx{m} = uicheckbox(tab(k-1),'Text',D,'Value', 1,'Position',new_pos,'Tag',num2str(m));
                        N = N+1;
                        m = m+1;
                    end
                end

                cond = table2array(T1(i,1));
                cond2 = cond{1,1};
                cond = str2double(cond2);
                
%                 if isempty(a) && (isempty(b)~=1)
                if (cond>0) ~= 1% && (isempty(b)~=1)
                    Tab_num = 1;
                    c = table2array(T1(i,3));
                    if isempty(c{1,1})==0
                        tabtitle = c{1,1};
                        tab(k) = uitab(tabgp,'Title',tabtitle);
                        tab(k).Scrollable = 'on';
                        if k > 1 
                            if N > 24
                                cbx_pos = [10 20*(N+2) 565 22];   
                            else
                                cbx_pos = [10 20*(24) 565 22];   
                            end
%                             cbx_pos = [10 200 565 22];
                            for j = n:n+N-1
                                cbx_pos-(j-n)*[0 20 0 0];
                                cbx{j}.Position = cbx_pos-(j-n)*[0 20 0 0];
                            end
                            tab_num = [tab_num; N];
                            n=n+N;
                        end
                        k=k+1;
                        N=0;
                    end
                end
            end
            tab_all = {};
%             cbx_temp = {};
            for i = 1:length(tab)
                tab_temp = tab(i);
                all_name = tab_temp.Title;
                all_name = "All : " + all_name;
%                 tab_all{i} = uicheckbox(tab(i),'Text',all_name,'Value', 0,'Position',[258 20 64 22],'Tag',num2str(i));         
                tab_all{i} = uicheckbox(tab(i),'Text',all_name,'Value', 0,'Position',[10 20 565 22],'Tag',num2str(i));         
                cbx_temp = tab_all{i};
                cbx_temp.ValueChangedFcn=(@(cbx_temp, event) all_check_callback(app, cbx_temp));
            end
            
            app.AllCheckBox.Value = 1;
            app.DieselCheckBox.Value = 1;
            app.BackupCheckBox.Value = 1;
            app.GasCheckBox.Value = 1;
            app.AllCheckBox_2.Value = 1;
            app.StopCheckBox.Value = 1;
            app.StartCheckBox.Value = 1;
            app.RunCheckBox.Value = 1;
            
            app.UIFigure.Visible = 'off' ;
            movegui(app.UIFigure, "center" )
            app.UIFigure.Visible = 'on' ;
            
            
            app.tab = tab;
            app.tab_all = tab_all;
            app.cbx = cbx;
            app.tab_num = tab_num;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cbx = app.cbx;
            tab_all = app.tab_all;
            for i = 1:length(cbx)
                cbx_temp = cbx{i};
                cbx_temp.Value = 1;
            end
            for i = 1:length(tab_all)
                all_temp = tab_all{i};
                all_temp.Value = 0;
            end
            app.AllCheckBox.Value = 1;
            app.DieselCheckBox.Value = 1;
            app.GasCheckBox.Value = 1;
            app.BackupCheckBox.Value = 1;
            app.AllCheckBox_2.Value = 1;
            app.RunCheckBox.Value = 1;
            app.StopCheckBox.Value = 1;
            app.StartCheckBox.Value = 1;
            app.AllCheckBox_4.Value = 0;
            
%             app.ListBox.Items = "";
%             app.ListBox_2.Items = "";
        end

        % Callback function
        function Tree_4CheckedNodesChanged(app, event)
            
        end

        % Callback function
        function Tree_3CheckedNodesChanged(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
%             app.ListBox.Items = app.item_box_empty;
%             app.ListBox_2.Items = app.item_box_empty;
            cbx = app.cbx;
            testcase = app.testcase;
            op = 0;
            fuel = 0;
            testname = [];
            for i = 1:length(cbx)
                cbx_temp = cbx{i};
                if cbx_temp.Value == 1
                    val = str2double(cbx_temp.Tag);
                    name = string(cbx_temp.Text);
                    testname = [testname name];
                    testcase = [testcase val];
                end
            end
            
            if app.DieselCheckBox.Value == 1
                fuel = fuel + 100;
            end
            if app.GasCheckBox.Value == 1
                fuel = fuel + 10;
            end
            if app.BackupCheckBox.Value == 1
                fuel = fuel + 1;
            end
            
            if app.RunCheckBox.Value == 1
                op = op + 100;
            end
            if app.StopCheckBox.Value == 1
                op = op + 10;
            end
            if app.StartCheckBox.Value == 1
                op = op + 1;
            end
            
            warnmsg_og = "Select";
            warnmsg = "Select";
            if length(testcase) == 0
                if isequal(warnmsg, warnmsg_og) ~= 1
                    warnmsg = warnmsg + ", Test(s)";
                else
                    warnmsg = warnmsg + " Test(s)";
                end
            end
            
            if fuel == 0
                if isequal(warnmsg, warnmsg_og) ~= 1
                    warnmsg = warnmsg + ", Fuel Mode";
                else
                    warnmsg = warnmsg + " Fuel Mode";
                end
            end
            
            if op == 0
                if isequal(warnmsg, warnmsg_og) ~= 1
                    warnmsg = warnmsg + ", Run Mode";
                else
                    warnmsg = warnmsg + " Run Mode";
                end
            end
            
%             PLC_num = app.PLCNoEditField.Value;
            PLC_num = app.PLCIPTextArea.Value;
            PLC_num = PLC_num{1,1};
%             testname
%             testcase
%             fuel
%             op
%             PLC_num
% 
            if isequal(warnmsg, warnmsg_og) ~= 1
                opts = struct('WindowStyle','modal', 'Interpreter','tex');
                warnmsg = '\fontsize{12} \color{red} ' + warnmsg;
                warndlg(warnmsg, 'Warning', opts)
            else
                result = Test_Runner_for_GUI(testname, testcase,fuel,op,PLC_num);
            end
            [testcase_n, ~] = size(result);
            Pass_test = [];
            Fail_test = [];
            for i = 1:testcase_n
                r = result{i,2};
                cbx_temp = cbx{result{i,1}};
                if r(1) == 1 % Success
                    Pass_test = [Pass_test; string(cbx_temp.Text)];
                else 
                    Fail_test = [Fail_test; string(cbx_temp.Text)];
                end
            end
            if length(Pass_test) ~= 0
                app.ListBox.Items = Pass_test;
            end
            if length(Fail_test) ~= 0
                app.ListBox_2.Items = Fail_test;
            end
        end

        % Callback function
        function TestLogTextAreaValueChanged(app, event)
            
        end

        % Callback function
        function TestLogTextAreaValueChanging(app, event)
            
        end

        % Callback function
        function TextArea_2ValueChanged(app, event)
            
        end

        % Callback function
        function TestLogTextAreaValueChanged2(app, event)
%             value = app.TestLogTextArea.Value;
            
        end

        % Callback function
        function AllCheckBox_4ValueChanged(app, event)
            
        end

        % Value changed function: AllCheckBox
        function AllCheckBoxValueChanged(app, event)
           value = app.AllCheckBox.Value;
            if value == 1
                app.DieselCheckBox.Value = 1;
                app.GasCheckBox.Value = 1;
                app.BackupCheckBox.Value = 1;
            elseif value == 0
                app.DieselCheckBox.Value = 0;
                app.GasCheckBox.Value = 0;
                app.BackupCheckBox.Value = 0;
            end
        end

        % Value changed function: AllCheckBox_2
        function AllCheckBox_2ValueChanged(app, event)
            value = app.AllCheckBox_2.Value;
            if value == 1
                app.RunCheckBox.Value = 1;
                app.StopCheckBox.Value = 1;
                app.StartCheckBox.Value = 1;
            elseif value == 0
                app.RunCheckBox.Value = 0;
                app.StopCheckBox.Value = 0;
                app.StartCheckBox.Value = 0;
            end
        end

        % Callback function
        function PT52CheckBoxValueChanged(app, event)
    
        end

        % Callback function
        function PT51CheckBoxValueChanged(app, event)
           
        end

        % Callback function
        function PT51PT52CheckBoxValueChanged(app, event)
      
        end

        % Callback function
        function TestLogTextArea_2ValueChanged(app, event)
            
        end

        % Callback function
        function AllCheckBox_6ValueChanged(app, event)
            
        end

        % Value changed function: AllCheckBox_4
        function AllCheckBox_4ValueChanged2(app, event)
%             global tab; global tab_all;
            tab = app.tab;
            tab_all = app.tab_all;
            cbx = app.cbx;
            value = app.AllCheckBox_4.Value;
            if value == 1
                for i = 1:length(tab)
                    ta = tab_all{i}; 
                    ta.Value = 1;
                end
                for i = 1:length(cbx)
                    ta = cbx{i}; 
                    ta.Value = 1;
                end
            end
            if value == 0
                for i = 1:length(tab)
                    ta = tab_all{i}; 
                    ta.Value = 0;
                end
                for i = 1:length(cbx)
                    ta = cbx{i}; 
                    ta.Value = 0;
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HHITestAutomationSuiteSafetyListLabel = uilabel(app.UIFigure);
            app.HHITestAutomationSuiteSafetyListLabel.Text = 'HHI Test Automation Suite - Safety List ';
            app.TestLogTextAreaLabel = uilabel(app.UIFigure);
            app.TestLogTextAreaLabel.Text = 'Success';
            app.TestLogTextAreaLabel_2 = uilabel(app.UIFigure);
            app.TestLogTextAreaLabel_2.Text = 'Failed';
            app.FuelModeLabel = uilabel(app.UIFigure);
            app.FuelModeLabel.Text = '  Fuel Mode';
            app.TextArea = uitextarea(app.UIFigure);
            app.BackupCheckBox = uicheckbox(app.UIFigure);
            app.BackupCheckBox.Text = 'Backup';
            app.GasCheckBox = uicheckbox(app.UIFigure);
            app.GasCheckBox.Text = 'Gas';
            app.DieselCheckBox = uicheckbox(app.UIFigure);
            app.DieselCheckBox.Text = 'Diesel';
            app.AllCheckBox = uicheckbox(app.UIFigure);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCheckBoxValueChanged, true);
            app.AllCheckBox.Text = 'All';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.RunModeLabel = uilabel(app.UIFigure);
            app.RunModeLabel.Text = '  Run Mode';
            app.RunCheckBox = uicheckbox(app.UIFigure);
            app.RunCheckBox.Text = 'Run';
            app.StartCheckBox = uicheckbox(app.UIFigure);
            app.StartCheckBox.Text = 'Start';
            app.StopCheckBox = uicheckbox(app.UIFigure);
            app.StopCheckBox.Text = 'Stop';
            app.AllCheckBox_2 = uicheckbox(app.UIFigure);
            app.AllCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AllCheckBox_2ValueChanged, true);
            app.AllCheckBox_2.Text = 'All';
            app.TestSelectionLabel = uilabel(app.UIFigure);
            app.TestSelectionLabel.Text = '  Test Selection';
            app.v001Label = uilabel(app.UIFigure);
            app.v001Label.Text = 'v 0.0.1';
            app.AllCheckBox_4 = uicheckbox(app.UIFigure);
            app.AllCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @AllCheckBox_4ValueChanged2, true);
            app.AllCheckBox_4.Text = 'All';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.PLCIPTextAreaLabel = uilabel(app.UIFigure);
            app.PLCIPTextAreaLabel.Text = 'PLC IP';
            app.PLCIPTextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SafetyList_Test_GUI_MATLAB_Runtime

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end